classdef LTEPredictParams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        PropertyDropDownLabel    matlab.ui.control.Label
        PropertyDropDown         matlab.ui.control.DropDown
        UITable                  matlab.ui.control.Table
        HistPDFCDFDropDownLabel  matlab.ui.control.Label
        HistPDFCDFDropDown       matlab.ui.control.DropDown
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % % Create an import options object from export_file_4G.xlsx
            [file,~] = uigetfile({'*.xlsx','Excel Workbook (*.xlsx)';...
                '*.xls','Excel 97-2003 Workbook (*.xls)'},'Select an Excel File');
            Optns = detectImportOptions(file);
            % Modify the Optns object to specify which variables to import
            Optns.SelectedVariableNames = {'No_','Longitude','Latitude',...
                'ServingRSRP_4G','ServingRSRQ_4G','ServingRSSI_4G','ServingPCCSINR_4G',...
                'ServingPCI_4G','ServingDLEARFCN_4G','ServingSCC1RSRP_4G','ServingSCC1RSRQ_4G',...
                'ServingSCC1RSSI_4G','ServingSCC1PCI_4G','ServingSCC1SINR_4G','ServingSCC1DLEARFCN_4G',...
                'PCCPUSCHPower_4G','PCCPUCCHPower_4G','PDCPThroughputDL_4G',...
                'LTERLCThroughputDL_4G'};
            % Use readtable along the Optns object to import the specified variables
            Table_4G = readtable(file,Optns);
            
            % Convert cells to matrix
            lons = cell2mat(Table_4G.Longitude);
            lats = cell2mat(Table_4G.Latitude);
            
            L_lons = length(lons);
            longi = repmat(char(0),L_lons,10);
            lati = repmat(char(0),L_lons,10);
            longNum = zeros(L_lons,1);
            latNum = zeros(L_lons,1);
            
            NoNum = zeros(L_lons,1);
            
            for i = 1:L_lons
                longi(i,:)=lons(i,:);
                lati(i,:)=lats(i,:);
                longNum(i) = sscanf(longi(i,:),'%f');
                latNum(i) = sscanf(lati(i,:),'%f');
                NoNum(i) = sscanf(Table_4G.No_{i},'%d');
            end
            longNum(42498) = longNum(L_lons);
            latNum(42498) = latNum(L_lons);
            NoNum(42498) = NoNum(L_lons)+1;
            Table_4G.NoNum = NoNum;
            app.UITable.Data = Table_4G;
            
            [RSRP_4G,~] = fillmissing(Table_4G.ServingRSRP_4G,'pchip',...
                'SamplePoints',NoNum);
            plot(app.UIAxes,NoNum,RSRP_4G,'r-',NoNum,Table_4G.ServingRSRP_4G,'b-')
            title(app.UIAxes,'Reference Signal Received Power');
            xlabel(app.UIAxes,'Locations of Measurements');
            ylabel(app.UIAxes,'Reference Signal Received Power (dBm)');
            legend(app.UIAxes,'Filled Missing RSRP','Original RSRP');
            
            histogram(app.UIAxes2,Table_4G.ServingRSRP_4G,'Normalization','pdf');
            app.UIAxes2.NextPlot = 'add';
            histogram(app.UIAxes2,RSRP_4G,'Normalization','pdf');
            pS_RSRP = histcounts(Table_4G.ServingRSRP_4G,'Normalization','pdf');
            p_RSRP = histcounts(RSRP_4G,'Normalization','pdf');
            
            RSRP_minO = min(Table_4G.ServingRSRP_4G);
            RSRP_min = min(RSRP_4G);
            RSRP_maxO = max(Table_4G.ServingRSRP_4G);
            RSRP_max = max(RSRP_4G);
            RSRP_meanO = mean(Table_4G.ServingRSRP_4G,'omitnan');
            RSRP_mean = mean(RSRP_4G);
            RSRP_stdO = std(Table_4G.ServingRSRP_4G,'omitnan');
            RSRP_std = std(RSRP_4G);
            
            RSRP_y = linspace(1.05*min(RSRP_minO,RSRP_min),1.05*max(RSRP_maxO,RSRP_max),length(p_RSRP));
            RSRP_f0 = exp(-(RSRP_y-RSRP_meanO).^2./(2*RSRP_stdO^2))./(RSRP_stdO*sqrt(2*pi));
            RSRP_f = exp(-(RSRP_y-RSRP_mean).^2./(2*RSRP_std^2))./(RSRP_std*sqrt(2*pi));
            plot(app.UIAxes2,RSRP_y,RSRP_f0,'LineWidth',3)
            plot(app.UIAxes2,RSRP_y,RSRP_f,'LineWidth',3)
            title(app.UIAxes2,{'Histogram and PDF of RSRP'});
            xlabel(app.UIAxes2,'Reference Signal Received Power (dBm)','FontWeight','bold');
            ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
            legend(app.UIAxes2,'Normalized Histogram of the Original RSRP Data','Normalize Histogram with Missing Data Filled',...
                'PDF Curve for Original RSRP Data','PDF Curve for Filled RSRP Data');
            
        end

        % Value changed function: PropertyDropDown
        function PropertyDropDownValueChanged(app, event)
            val = app.PropertyDropDown.Value;
            Table_4G = app.UITable.DisplayData;
            NoNum = Table_4G.NoNum;
            switch val
                case 'RSRP'
                    [RSRP_4G,~] = fillmissing(Table_4G.ServingRSRP_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,RSRP_4G,'r-',NoNum,Table_4G.ServingRSRP_4G,'b-')
                    title(app.UIAxes,'Reference Signal Received Power');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'Reference Signal Received Power (dBm)');
                    legend(app.UIAxes,'Filled Missing RSRP','Original RSRP');
                    
                case 'RSRQ'
                    [RSRQ_4G,~] = fillmissing(Table_4G.ServingRSRQ_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,RSRQ_4G,'r-',NoNum,Table_4G.ServingRSRQ_4G,'b-')
                    title(app.UIAxes,'Reference Signal Received Quality');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'Reference Signal Received Quality (dB)');
                    legend(app.UIAxes,'Filled Missing RSRQ','Original RSRQ');
                    
                case 'RSSI'
                    [RSSI_4G,~] = fillmissing(Table_4G.ServingRSSI_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,RSSI_4G,'r-',NoNum,Table_4G.ServingRSSI_4G,'b-')
                    title(app.UIAxes,'Received Signal Strength Indicator');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'Received Signal Strength Indicator (dBm)');
                    legend(app.UIAxes,'Filled Missing RSSI','Original RSSI');
                    
                case 'PCCSINR'
                    [PCCSINR_4G,~] = fillmissing(Table_4G.ServingPCCSINR_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,PCCSINR_4G,'r-',NoNum,Table_4G.ServingPCCSINR_4G,'b-');
                    title(app.UIAxes,'Primary Component Carrier SINR');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'Primary Component Carrier SINR (dBm)');
                    legend(app.UIAxes,'Filled Missing PCC SINR','Original PCC SINR');
                    
                case 'PCI'
                    [PCI_4G,~] = fillmissing(Table_4G.ServingPCI_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,PCI_4G,'r-',NoNum,Table_4G.ServingPCI_4G,'b-')
                    title(app.UIAxes,'Physical Cell Identity');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'Physical Cell Identity (dBm)');
                    legend(app.UIAxes,'Filled Missing PCI','Original PCI');
                    
                case 'DLEARFCN'
                    [DLEARFCN_4G,~] = fillmissing(Table_4G.ServingDLEARFCN_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,DLEARFCN_4G,'r-',NoNum,Table_4G.ServingDLEARFCN_4G,'b-')
                    title(app.UIAxes,'Downlink E-UTRA Absolute Radio Frequency Channel Number');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'DL EARFCN (dBm)');
                    legend(app.UIAxes,'Filled Missing DL EARFCN','Original DL EARFCN');
                    
                case 'SCC1RSRP'
                    [SCC1RSRP_4G,~] = fillmissing(Table_4G.ServingSCC1RSRP_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,SCC1RSRP_4G,'r-',NoNum,Table_4G.ServingSCC1RSRP_4G,'b-')
                    title(app.UIAxes,'1st Secondary Component Carrier RSRP');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'SCC1 RSRP (dBm)');
                    legend(app.UIAxes,'Filled Missing SCC1 RSRP','Original SCC1 RSRP');
                    
                case 'SCC1RSRQ'
                    [SCC1RSRQ_4G,~] = fillmissing(Table_4G.ServingSCC1RSRQ_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,SCC1RSRQ_4G,'r-',NoNum,Table_4G.ServingSCC1RSRQ_4G,'b-')
                    title(app.UIAxes,'1st Secondary Component Carrier RSRQ');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'SCC1 RSRQ (dBm)');
                    legend(app.UIAxes,'Filled Missing SCC1 RSRQ','Original SCC1 RSRQ');
                    
                case 'SCC1RSSI'
                    [SCC1RSSI_4G,~] = fillmissing(Table_4G.ServingSCC1RSSI_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,SCC1RSSI_4G,'r-',NoNum,Table_4G.ServingSCC1RSSI_4G,'b-')
                    title(app.UIAxes,'1st Secondary Component Carrier RSSI');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'SCC1 RSSI (dBm)');
                    legend(app.UIAxes,'Filled Missing SCC1 RSSI','Original SCC1 RSSI');
                    
                case 'SCC1PCI'
                    [SCC1PCI_4G,~] = fillmissing(Table_4G.ServingSCC1PCI_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,SCC1PCI_4G,'r-',NoNum,Table_4G.ServingSCC1PCI_4G,'b-')
                    title(app.UIAxes,'1st Secondary Component Carrier PCI');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'SCC1 PCI (dBm)');
                    legend(app.UIAxes,'Filled Missing SCC1 PCI','Original SCC1 PCI');
                    
                case 'SCC1SINR'
                    [SCC1SINR_4G,~] = fillmissing(Table_4G.ServingSCC1SINR_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,SCC1SINR_4G,'r-',NoNum,Table_4G.ServingSCC1SINR_4G,'b-')
                    title(app.UIAxes,'1st Secondary Component Carrier SINR');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'SCC1 SINR (dBm)');
                    legend(app.UIAxes,'Filled Missing SCC1 SINR','Original SCC1 SINR');
                    
                case 'SCC1DLEARFCN'
                    [SCC1DLEARFCN_4G,~] = fillmissing(Table_4G.ServingSCC1DLEARFCN_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,SCC1DLEARFCN_4G,'r-',NoNum,Table_4G.ServingSCC1DLEARFCN_4G,'b-')
                    title(app.UIAxes,'1st Secondary Component Carrier DL EARFCN');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'SCC1 DL EARFCN (dBm)');
                    legend(app.UIAxes,'Filled Missing SCC1 DL EARFCN','Original SCC1 DL EARFCN');
                    
                case 'PCCPUSCHPower'
                    [PCCPUSCHPower_4G,~] = fillmissing(Table_4G.PCCPUSCHPower_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,PCCPUSCHPower_4G,'r-',NoNum,Table_4G.PCCPUSCHPower_4G,'b-')
                    title(app.UIAxes,'PCC Physical Uplink Shared Channel Power');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'PCC PUSCH Power (dBm)');
                    legend(app.UIAxes,'Filled Missing PCC PUSCH Power','Original PCC PUSCH Power');
                    
                case 'PCCPUCCHPower'
                    [PCCPUCCHPower_4G,~] = fillmissing(Table_4G.PCCPUCCHPower_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,PCCPUCCHPower_4G,'r-',NoNum,Table_4G.PCCPUCCHPower_4G,'b-')
                    title(app.UIAxes,'PCC Physical Uplink Control Channel Power');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'PCC PUSCH Power (dBm)');
                    legend(app.UIAxes,'Filled Missing PCC PUCCH Power','Original PCC PUCCH Power');
                    
                case 'PDCPThroughputDL'
                    [PDCPThroughputDL_4G,~] = fillmissing(Table_4G.PDCPThroughputDL_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,PDCPThroughputDL_4G,'r-',NoNum,Table_4G.PDCPThroughputDL_4G,'b-')
                    title(app.UIAxes,'Packet Data Convergence Protocol Downlink Throughput');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'PDCP Throughput DL (dBm)');
                    legend(app.UIAxes,'Filled Missing PDCP Throughput DL','Original PDCP Throughput DL');
                    
                case 'LTERLCThroughputDL'
                    [LTERLCThroughputDL_4G,~] = fillmissing(Table_4G.LTERLCThroughputDL_4G,'pchip',...
                        'SamplePoints',NoNum);
                    plot(app.UIAxes,NoNum,LTERLCThroughputDL_4G,'r-',NoNum,Table_4G.LTERLCThroughputDL_4G,'b-')
                    title(app.UIAxes,'LTE Radio Link Control Downlink Throughput');
                    xlabel(app.UIAxes,'Locations of Measurements');
                    ylabel(app.UIAxes,'LTE RLC Throughput DL (dBm)');
                    legend(app.UIAxes,'Filled Missing LTE RLC Throughput DL','Original LTE RLC Throughput DL');
                    
            end
        end

        % Value changed function: HistPDFCDFDropDown
        function HistPDFCDFDropDownValueChanged(app, event)
            val = app.HistPDFCDFDropDown.Value;
            Table_4G = app.UITable.DisplayData;
            NoNum = Table_4G.NoNum;
            
            switch val
                case 'RSRP'
                    [RSRP_4G,~] = fillmissing(Table_4G.ServingRSRP_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.ServingRSRP_4G,'Normalization','pdf');
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,RSRP_4G,'Normalization','pdf');
                    pS_RSRP = histcounts(Table_4G.ServingRSRP_4G,'Normalization','pdf');
                    p_RSRP = histcounts(RSRP_4G,'Normalization','pdf');
                    
                    RSRP_minO = min(Table_4G.ServingRSRP_4G);
                    RSRP_min = min(RSRP_4G);
                    RSRP_maxO = max(Table_4G.ServingRSRP_4G);
                    RSRP_max = max(RSRP_4G);
                    RSRP_meanO = mean(Table_4G.ServingRSRP_4G,'omitnan');
                    RSRP_mean = mean(RSRP_4G);
                    RSRP_stdO = std(Table_4G.ServingRSRP_4G,'omitnan');
                    RSRP_std = std(RSRP_4G);
                    
                    RSRP_y = linspace(1.05*min(RSRP_minO,RSRP_min),1.05*max(RSRP_maxO,RSRP_max),length(p_RSRP));
                    RSRP_f0 = exp(-(RSRP_y-RSRP_meanO).^2./(2*RSRP_stdO^2))./(RSRP_stdO*sqrt(2*pi));
                    RSRP_f = exp(-(RSRP_y-RSRP_mean).^2./(2*RSRP_std^2))./(RSRP_std*sqrt(2*pi));
                    plot(app.UIAxes2,RSRP_y,RSRP_f0,'LineWidth',3)
                    plot(app.UIAxes2,RSRP_y,RSRP_f,'LineWidth',3)
                    title(app.UIAxes2,{'Histogram and PDF of RSRP'});
                    xlabel(app.UIAxes2,'Reference Signal Received Power (dBm)','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original RSRP Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original RSRP Data','PDF Curve for Filled RSRP Data');
                    
                case 'RSRQ'
                    [RSRQ_4G,~] = fillmissing(Table_4G.ServingRSRQ_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.ServingRSRQ_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,RSRQ_4G,'Normalization','pdf')
                    pS_RSRQ = histcounts(Table_4G.ServingRSRQ_4G,'Normalization','pdf');
                    p_RSRQ = histcounts(RSRQ_4G,'Normalization','pdf');
                    
                    RSRQ_minO = min(Table_4G.ServingRSRQ_4G);
                    RSRQ_min = min(RSRQ_4G);
                    RSRQ_maxO = max(Table_4G.ServingRSRQ_4G);
                    RSRQ_max = max(RSRQ_4G);
                    RSRQ_meanO = mean(Table_4G.ServingRSRQ_4G,'omitnan');
                    RSRQ_mean = mean(RSRQ_4G);
                    RSRQ_stdO = std(Table_4G.ServingRSRQ_4G,'omitnan');
                    RSRQ_std = std(RSRQ_4G);
                    
                    RSRQ_y = linspace(1.05*min(RSRQ_minO,RSRQ_min),1.05*max(RSRQ_maxO,RSRQ_max),length(p_RSRQ));
                    RSRQ_f0 = exp(-(RSRQ_y-RSRQ_meanO).^2./(2*RSRQ_stdO^2))./(RSRQ_stdO*sqrt(2*pi));
                    RSRQ_f = exp(-(RSRQ_y-RSRQ_mean).^2./(2*RSRQ_std^2))./(RSRQ_std*sqrt(2*pi));
                    plot(app.UIAxes2,RSRQ_y,RSRQ_f0,'LineWidth',2)
                    plot(app.UIAxes2,RSRQ_y,RSRQ_f,'LineWidth',2)
                    title(app.UIAxes2,{'Histogram and PDF of RSRQ'});
                    xlabel(app.UIAxes2,'Reference Signal Received Quality (dBm)','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original RSRQ Data','Normalized Histogram with Missing Data Filled',...
                        'PDF Curve for Original RSRQ Data','PDF Curve for Filled RSRQ Data');
                    
                case 'RSSI'
                    [RSSI_4G,~] = fillmissing(Table_4G.ServingRSSI_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.ServingRSSI_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,RSSI_4G,'Normalization','pdf')
                    pS_RSSI = histcounts(Table_4G.ServingRSSI_4G,'Normalization','pdf');
                    p_RSSI = histcounts(RSSI_4G,'Normalization','pdf');
                    
                    RSSI_minO = min(Table_4G.ServingRSSI_4G);
                    RSSI_min = min(RSSI_4G);
                    RSSI_maxO = max(Table_4G.ServingRSSI_4G);
                    RSSI_max = max(RSSI_4G);
                    RSSI_meanO = mean(Table_4G.ServingRSSI_4G,'omitnan');
                    RSSI_mean = mean(RSSI_4G);
                    RSSI_stdO = std(Table_4G.ServingRSSI_4G,'omitnan');
                    RSSI_std = std(RSSI_4G);
                    
                    RSSI_y = linspace(1.05*min(RSSI_minO,RSSI_min),1.05*max(RSSI_maxO,RSSI_max),length(p_RSSI));
                    RSSI_f0 = exp(-(RSSI_y-RSSI_meanO).^2./(2*RSSI_stdO^2))./(RSSI_stdO*sqrt(2*pi));
                    RSSI_f = exp(-(RSSI_y-RSSI_mean).^2./(2*RSSI_std^2))./(RSSI_std*sqrt(2*pi));
                    plot(app.UIAxes2,RSSI_y,RSSI_f0,'LineWidth',3)
                    plot(app.UIAxes2,RSSI_y,RSSI_f,'LineWidth',3)
                    title(app.UIAxes2,{'Histogram and PDF of RSSI'});
                    xlabel(app.UIAxes2,'Received Signal Strength Indicator (dBm)','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original RSSI Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original RSSI Data','PDF Curve for Filled RSSI Data');

                    
                case 'PCCSINR'
                    [PCCSINR_4G,~] = fillmissing(Table_4G.ServingPCCSINR_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.ServingPCCSINR_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,PCCSINR_4G,'Normalization','pdf')
                    pS_PCCSINR = histcounts(Table_4G.ServingPCCSINR_4G,'Normalization','pdf');
                    p_PCCSINR = histcounts(PCCSINR_4G,'Normalization','pdf');
                    
                    PCCSINR_minO = min(Table_4G.ServingPCCSINR_4G);
                    PCCSINR_min = min(PCCSINR_4G);
                    PCCSINR_maxO = max(Table_4G.ServingPCCSINR_4G);
                    PCCSINR_max = max(PCCSINR_4G);
                    PCCSINR_meanO = mean(Table_4G.ServingPCCSINR_4G,'omitnan');
                    PCCSINR_mean = mean(PCCSINR_4G);
                    PCCSINR_stdO = std(Table_4G.ServingPCCSINR_4G,'omitnan');
                    PCCSINR_std = std(PCCSINR_4G);
                    
                    PCCSINR_y = linspace(1.05*min(PCCSINR_minO,PCCSINR_min),1.05*max(PCCSINR_maxO,PCCSINR_max),length(p_PCCSINR));
                    PCCSINR_f0 = exp(-(PCCSINR_y-PCCSINR_meanO).^2./(2*PCCSINR_stdO^2))./(PCCSINR_stdO*sqrt(2*pi));
                    PCCSINR_f = exp(-(PCCSINR_y-PCCSINR_mean).^2./(2*PCCSINR_std^2))./(PCCSINR_std*sqrt(2*pi));
                    plot(app.UIAxes2,PCCSINR_y,PCCSINR_f0,'LineWidth',3)
                    plot(app.UIAxes2,PCCSINR_y,PCCSINR_f,'LineWidth',3)
                    title(app.UIAxes2,{'Histogram and PDF of PCC SINR'});
                    xlabel(app.UIAxes2,'Primary Component Carrier SINR (dB)','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original PCCSINR Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original PCCSINR Data','PDF Curve for Filled PCCSINR Data');
                    
                case 'PCI'
                    [PCI_4G,~] = fillmissing(Table_4G.ServingPCI_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.ServingPCI_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,PCI_4G,'Normalization','pdf')
                    pS_PCI = histcounts(Table_4G.ServingPCI_4G,'Normalization','pdf');
                    p_PCI = histcounts(PCI_4G,'Normalization','pdf');
                    
                    PCI_minO = min(Table_4G.ServingPCI_4G);
                    PCI_min = min(PCI_4G);
                    PCI_maxO = max(Table_4G.ServingPCI_4G);
                    PCI_max = max(PCI_4G);
                    PCI_meanO = mean(Table_4G.ServingPCI_4G,'omitnan');
                    PCI_mean = mean(PCI_4G);
                    PCI_stdO = std(Table_4G.ServingPCI_4G,'omitnan');
                    PCI_std = std(PCI_4G);
                    
                    PCI_y = linspace(1.05*min(PCI_minO,PCI_min),1.05*max(PCI_maxO,PCI_max),length(p_PCI));
                    PCI_f0 = exp(-(PCI_y-PCI_meanO).^2./(2*PCI_stdO^2))./(PCI_stdO*sqrt(2*pi));
                    PCI_f = exp(-(PCI_y-PCI_mean).^2./(2*PCI_std^2))./(PCI_std*sqrt(2*pi));
                    plot(app.UIAxes2,PCI_y,PCI_f0,'LineWidth',3)
                    plot(app.UIAxes2,PCI_y,PCI_f,'LineWidth',3)
                    title(app.UIAxes2,{'Histogram and PDF of Physical Cell Identity'});
                    xlabel(app.UIAxes2,'Physical Cell Identity','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original PCI Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original PCI Data','PDF Curve for Filled PCI Data');
                    
                case 'DLEARFCN'
                    [DLEARFCN_4G,~] = fillmissing(Table_4G.ServingDLEARFCN_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.ServingDLEARFCN_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,DLEARFCN_4G,'Normalization','pdf')
                    pS_DLEARFCN = histcounts(Table_4G.ServingDLEARFCN_4G,'Normalization','pdf');
                    p_DLEARFCN = histcounts(DLEARFCN_4G,'Normalization','pdf');
                    
                    DLEARFCN_minO = min(Table_4G.ServingDLEARFCN_4G);
                    DLEARFCN_min = min(DLEARFCN_4G);
                    DLEARFCN_maxO = max(Table_4G.ServingDLEARFCN_4G);
                    DLEARFCN_max = max(DLEARFCN_4G);
                    DLEARFCN_meanO = mean(Table_4G.ServingDLEARFCN_4G,'omitnan');
                    DLEARFCN_mean = mean(DLEARFCN_4G);
                    DLEARFCN_stdO = std(Table_4G.ServingDLEARFCN_4G,'omitnan');
                    DLEARFCN_std = std(DLEARFCN_4G);
                    
                    DLEARFCN_y = linspace(1.05*min(DLEARFCN_minO,DLEARFCN_min),1.05*max(DLEARFCN_maxO,DLEARFCN_max),length(p_DLEARFCN));
                    DLEARFCN_f0 = exp(-(DLEARFCN_y-DLEARFCN_meanO).^2./(2*DLEARFCN_stdO^2))./(DLEARFCN_stdO*sqrt(2*pi));
                    DLEARFCN_f = exp(-(DLEARFCN_y-DLEARFCN_mean).^2./(2*DLEARFCN_std^2))./(DLEARFCN_std*sqrt(2*pi));
                    plot(app.UIAxes2,DLEARFCN_y,DLEARFCN_f0,'LineWidth',3)
                    plot(app.UIAxes2,DLEARFCN_y,DLEARFCN_f,'LineWidth',3)
                    title(app.UIAxes2,{'Histogram and PDF of DL EARFCN'});
                    xlabel(app.UIAxes2,'E-UTRA Absolute Radio Frequency Channel Number','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original DLEARFCN Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original DLEARFCN Data','PDF Curve for Filled DLEARFCN Data');
                    
                case 'SCC1RSRP'
                    [SCC1RSRP_4G,~] = fillmissing(Table_4G.ServingSCC1RSRP_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.ServingSCC1RSRP_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,SCC1RSRP_4G,'Normalization','pdf')
                    pS_SCC1RSRP = histcounts(Table_4G.ServingSCC1RSRP_4G,'Normalization','pdf');
                    p_SCC1RSRP = histcounts(SCC1RSRP_4G,'Normalization','pdf');
                    
                    SCC1RSRP_minO = min(Table_4G.ServingSCC1RSRP_4G);
                    SCC1RSRP_min = min(SCC1RSRP_4G);
                    SCC1RSRP_maxO = max(Table_4G.ServingSCC1RSRP_4G);
                    SCC1RSRP_max = max(SCC1RSRP_4G);
                    SCC1RSRP_meanO = mean(Table_4G.ServingSCC1RSRP_4G,'omitnan');
                    SCC1RSRP_mean = mean(SCC1RSRP_4G);
                    SCC1RSRP_stdO = std(Table_4G.ServingSCC1RSRP_4G,'omitnan');
                    SCC1RSRP_std = std(SCC1RSRP_4G);
                    
                    SCC1RSRP_y = linspace(1.05*min(SCC1RSRP_minO,SCC1RSRP_min),1.05*max(SCC1RSRP_maxO,SCC1RSRP_max),length(p_SCC1RSRP));
                    if length(p_SCC1RSRP) ~= length(pS_SCC1RSRP)
                        SCC1RSRP_y0 = linspace(1.05*min(SCC1RSRP_minO,SCC1RSRP_min),1.05*max(SCC1RSRP_maxO,SCC1RSRP_max),length(pS_SCC1RSRP));
                        SCC1RSRP_f0 = exp(-(SCC1RSRP_y0-SCC1RSRP_meanO).^2./(2*SCC1RSRP_stdO^2))./(SCC1RSRP_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,SCC1RSRP_y0,SCC1RSRP_f0,'LineWidth',3)
                    else
                        SCC1RSRP_f0 = exp(-(SCC1RSRP_y-SCC1RSRP_meanO).^2./(2*SCC1RSRP_stdO^2))./(SCC1RSRP_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,SCC1RSRP_y,SCC1RSRP_f0,'LineWidth',3)
                    end
                    SCC1RSRP_f = exp(-(SCC1RSRP_y-SCC1RSRP_mean).^2./(2*SCC1RSRP_std^2))./(SCC1RSRP_std*sqrt(2*pi));
                    plot(app.UIAxes2,SCC1RSRP_y,SCC1RSRP_f,'LineWidth',3)
                    title(app.UIAxes2,{'Histogram and PDF of ', 'SCC1 RSRP'});
                    xlabel(app.UIAxes2,'SCC1 RSRP (dBm)','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original SCC1 RSRP Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original SCC1 RSRP Data','PDF Curve for Filled SCC1 RSRP Data');
                    
                case 'SCC1RSRQ'
                    [SCC1RSRQ_4G,~] = fillmissing(Table_4G.ServingSCC1RSRQ_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.ServingSCC1RSRQ_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,SCC1RSRQ_4G,'Normalization','pdf')
                    pS_SCC1RSRQ = histcounts(Table_4G.ServingSCC1RSRQ_4G,'Normalization','pdf');
                    p_SCC1RSRQ = histcounts(SCC1RSRQ_4G,'Normalization','pdf');
                    
                    SCC1RSRQ_minO = min(Table_4G.ServingSCC1RSRQ_4G);
                    SCC1RSRQ_min = min(SCC1RSRQ_4G);
                    SCC1RSRQ_maxO = max(Table_4G.ServingSCC1RSRQ_4G);
                    SCC1RSRQ_max = max(SCC1RSRQ_4G);
                    SCC1RSRQ_meanO = mean(Table_4G.ServingSCC1RSRQ_4G,'omitnan');
                    SCC1RSRQ_mean = mean(SCC1RSRQ_4G);
                    SCC1RSRQ_stdO = std(Table_4G.ServingSCC1RSRQ_4G,'omitnan');
                    SCC1RSRQ_std = std(SCC1RSRQ_4G);
                    
                    SCC1RSRQ_y = linspace(1.05*min(SCC1RSRQ_minO,SCC1RSRQ_min),1.05*max(SCC1RSRQ_maxO,SCC1RSRQ_max),length(p_SCC1RSRQ));
                    if length(p_SCC1RSRQ) ~= length(pS_SCC1RSRQ)
                        SCC1RSRQ_y0 = linspace(1.05*min(SCC1RSRQ_minO,SCC1RSRQ_min),1.05*max(SCC1RSRQ_maxO,SCC1RSRQ_max),length(pS_SCC1RSRQ));
                        SCC1RSRQ_f0 = exp(-(SCC1RSRQ_y0-SCC1RSRQ_meanO).^2./(2*SCC1RSRQ_stdO^2))./(SCC1RSRQ_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,SCC1RSRQ_y0,SCC1RSRQ_f0,'LineWidth',3)
                    else
                        SCC1RSRQ_f0 = exp(-(SCC1RSRQ_y-SCC1RSRQ_meanO).^2./(2*SCC1RSRQ_stdO^2))./(SCC1RSRQ_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,SCC1RSRQ_y,SCC1RSRQ_f0,'LineWidth',3)
                    end
                    SCC1RSRQ_f = exp(-(SCC1RSRQ_y-SCC1RSRQ_mean).^2./(2*SCC1RSRQ_std^2))./(SCC1RSRQ_std*sqrt(2*pi));
                    plot(app.UIAxes2,SCC1RSRQ_y,SCC1RSRQ_f,'LineWidth',3)
                    title(app.UIAxes2,{'Histogram and PDF of SCC1 RSRQ'});
                    xlabel(app.UIAxes2,'SCC1 RSRQ (dBm)','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original SCC1 RSRQ Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original SCC1 RSRQ Data','PDF Curve for Filled SCC1 RSRQ Data');
 
                    
                case 'SCC1RSSI'
                    [SCC1RSSI_4G,~] = fillmissing(Table_4G.ServingSCC1RSSI_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.ServingSCC1RSSI_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,SCC1RSSI_4G,'Normalization','pdf')
                    pS_SCC1RSSI = histcounts(Table_4G.ServingSCC1RSSI_4G,'Normalization','pdf');
                    p_SCC1RSSI = histcounts(SCC1RSSI_4G,'Normalization','pdf');
                    
                    SCC1RSSI_minO = min(Table_4G.ServingSCC1RSSI_4G);
                    SCC1RSSI_min = min(SCC1RSSI_4G);
                    SCC1RSSI_maxO = max(Table_4G.ServingSCC1RSSI_4G);
                    SCC1RSSI_max = max(SCC1RSSI_4G);
                    SCC1RSSI_meanO = mean(Table_4G.ServingSCC1RSSI_4G,'omitnan');
                    SCC1RSSI_mean = mean(SCC1RSSI_4G);
                    SCC1RSSI_stdO = std(Table_4G.ServingSCC1RSSI_4G,'omitnan');
                    SCC1RSSI_std = std(SCC1RSSI_4G);
                    
                    SCC1RSSI_y = linspace(1.05*min(SCC1RSSI_minO,SCC1RSSI_min),1.05*max(SCC1RSSI_maxO,SCC1RSSI_max),length(p_SCC1RSSI));
                    if length(p_SCC1RSSI) ~= length(pS_SCC1RSSI)
                        SCC1RSSI_y0 = linspace(1.05*min(SCC1RSSI_minO,SCC1RSSI_min),1.05*max(SCC1RSSI_maxO,SCC1RSSI_max),length(pS_SCC1RSSI));
                        SCC1RSSI_f0 = exp(-(SCC1RSSI_y0-SCC1RSSI_meanO).^2./(2*SCC1RSSI_stdO^2))./(SCC1RSSI_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,SCC1RSSI_y0,SCC1RSSI_f0,'LineWidth',3)
                    else
                        SCC1RSSI_f0 = exp(-(SCC1RSSI_y-SCC1RSSI_meanO).^2./(2*SCC1RSSI_stdO^2))./(SCC1RSSI_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,SCC1RSSI_y,SCC1RSSI_f0,'LineWidth',3)
                    end
                    SCC1RSSI_f = exp(-(SCC1RSSI_y-SCC1RSSI_mean).^2./(2*SCC1RSSI_std^2))./(SCC1RSSI_std*sqrt(2*pi));
                    plot(app.UIAxes2,SCC1RSSI_y,SCC1RSSI_f,'LineWidth',3)

                    title(app.UIAxes2,{'Histogram and PDF of SCC1 RSSI'});
                    xlabel(app.UIAxes2,'SCC1 RSSI (dBm)','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original SCC1 RSSI Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original SCC1 RSSI Data','PDF Curve for Filled SCC1 RSSI Data');
                    
                case 'SCC1PCI'
                    [SCC1PCI_4G,~] = fillmissing(Table_4G.ServingSCC1PCI_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.ServingSCC1PCI_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,SCC1PCI_4G,'Normalization','pdf')
                    pS_SCC1PCI = histcounts(Table_4G.ServingSCC1PCI_4G,'Normalization','pdf');
                    p_SCC1PCI = histcounts(SCC1PCI_4G,'Normalization','pdf');
                    
                    SCC1PCI_minO = min(Table_4G.ServingSCC1PCI_4G);
                    SCC1PCI_min = min(SCC1PCI_4G);
                    SCC1PCI_maxO = max(Table_4G.ServingSCC1PCI_4G);
                    SCC1PCI_max = max(SCC1PCI_4G);
                    SCC1PCI_meanO = mean(Table_4G.ServingSCC1PCI_4G,'omitnan');
                    SCC1PCI_mean = mean(SCC1PCI_4G);
                    SCC1PCI_stdO = std(Table_4G.ServingSCC1PCI_4G,'omitnan');
                    SCC1PCI_std = std(SCC1PCI_4G);
                    
                    SCC1PCI_y = linspace(1.05*min(SCC1PCI_minO,SCC1PCI_min),1.05*max(SCC1PCI_maxO,SCC1PCI_max),length(p_SCC1PCI));
                    if length(p_SCC1PCI) ~= length(pS_SCC1PCI)
                        SCC1PCI_y0 = linspace(1.05*min(SCC1PCI_minO,SCC1PCI_min),1.05*max(SCC1PCI_maxO,SCC1PCI_max),length(pS_SCC1PCI));
                        SCC1PCI_f0 = exp(-(SCC1PCI_y0-SCC1PCI_meanO).^2./(2*SCC1PCI_stdO^2))./(SCC1PCI_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,SCC1PCI_y0,SCC1PCI_f0,'LineWidth',3)
                    else
                        SCC1PCI_f0 = exp(-(SCC1PCI_y-SCC1PCI_meanO).^2./(2*SCC1PCI_stdO^2))./(SCC1PCI_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,SCC1PCI_y,SCC1PCI_f0,'LineWidth',3)
                    end
                    SCC1PCI_f = exp(-(SCC1PCI_y-SCC1PCI_mean).^2./(2*SCC1PCI_std^2))./(SCC1PCI_std*sqrt(2*pi));
                    plot(app.UIAxes2,SCC1PCI_y,SCC1PCI_f,'LineWidth',3)

                    title(app.UIAxes2,{'Histogram and PDF of SCC1 PCI'});
                    xlabel(app.UIAxes2,'SCC1 PCI','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original SCC1 PCI Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original SCC1 PCI Data','PDF Curve for Filled SCC1 PCI Data');
                    
                case 'SCC1SINR'
                    [SCC1SINR_4G,~] = fillmissing(Table_4G.ServingSCC1SINR_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.ServingSCC1SINR_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,SCC1SINR_4G,'Normalization','pdf')
                    pS_SCC1SINR = histcounts(Table_4G.ServingSCC1SINR_4G,'Normalization','pdf');
                    p_SCC1SINR = histcounts(SCC1SINR_4G,'Normalization','pdf');
                    
                    SCC1SINR_minO = min(Table_4G.ServingSCC1SINR_4G);
                    SCC1SINR_min = min(SCC1SINR_4G);
                    SCC1SINR_maxO = max(Table_4G.ServingSCC1SINR_4G);
                    SCC1SINR_max = max(SCC1SINR_4G);
                    SCC1SINR_meanO = mean(Table_4G.ServingSCC1SINR_4G,'omitnan');
                    SCC1SINR_mean = mean(SCC1SINR_4G);
                    SCC1SINR_stdO = std(Table_4G.ServingSCC1SINR_4G,'omitnan');
                    SCC1SINR_std = std(SCC1SINR_4G);
                    
                    SCC1SINR_y = linspace(1.05*min(SCC1SINR_minO,SCC1SINR_min),1.05*max(SCC1SINR_maxO,SCC1SINR_max),length(p_SCC1SINR));
                    if length(p_SCC1SINR) ~= length(pS_SCC1SINR)
                        SCC1SINR_y0 = linspace(1.05*min(SCC1SINR_minO,SCC1SINR_min),1.05*max(SCC1SINR_maxO,SCC1SINR_max),length(pS_SCC1SINR));
                        SCC1SINR_f0 = exp(-(SCC1SINR_y0-SCC1SINR_meanO).^2./(2*SCC1SINR_stdO^2))./(SCC1SINR_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,SCC1SINR_y0,SCC1SINR_f0,'LineWidth',3)
                    else
                        SCC1SINR_f0 = exp(-(SCC1SINR_y-SCC1SINR_meanO).^2./(2*SCC1SINR_stdO^2))./(SCC1SINR_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,SCC1SINR_y,SCC1SINR_f0,'LineWidth',3)
                    end
                    SCC1SINR_f = exp(-(SCC1SINR_y-SCC1SINR_mean).^2./(2*SCC1SINR_std^2))./(SCC1SINR_std*sqrt(2*pi));
                    plot(app.UIAxes2,SCC1SINR_y,SCC1SINR_f,'LineWidth',3)
                    title(app.UIAxes2,{'Histogram and PDF of SCC1 SINR'});
                    xlabel(app.UIAxes2,'SCC1 SINR (dB)','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original SCC1 SINR Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original SCC1 SINR Data','PDF Curve for Filled SCC1 SINR Data');
                    
                case 'SCC1DLEARFCN'
                    [SCC1DLEARFCN_4G,~] = fillmissing(Table_4G.ServingSCC1DLEARFCN_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.ServingSCC1DLEARFCN_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,SCC1DLEARFCN_4G,'Normalization','pdf')
                    pS_SCC1DLEARFCN = histcounts(Table_4G.ServingSCC1DLEARFCN_4G,'Normalization','pdf');
                    p_SCC1DLEARFCN = histcounts(SCC1DLEARFCN_4G,'Normalization','pdf');
                    
                    SCC1DLEARFCN_minO = min(Table_4G.ServingSCC1DLEARFCN_4G);
                    SCC1DLEARFCN_min = min(SCC1DLEARFCN_4G);
                    SCC1DLEARFCN_maxO = max(Table_4G.ServingSCC1DLEARFCN_4G);
                    SCC1DLEARFCN_max = max(SCC1DLEARFCN_4G);
                    SCC1DLEARFCN_meanO = mean(Table_4G.ServingSCC1DLEARFCN_4G,'omitnan');
                    SCC1DLEARFCN_mean = mean(SCC1DLEARFCN_4G);
                    SCC1DLEARFCN_stdO = std(Table_4G.ServingSCC1DLEARFCN_4G,'omitnan');
                    SCC1DLEARFCN_std = std(SCC1DLEARFCN_4G);
                    
                    SCC1DLEARFCN_y = linspace(1.05*min(SCC1DLEARFCN_minO,SCC1DLEARFCN_min),1.05*max(SCC1DLEARFCN_maxO,SCC1DLEARFCN_max),length(p_SCC1DLEARFCN));
                    if length(p_SCC1DLEARFCN) ~= length(pS_SCC1DLEARFCN)
                        SCC1DLEARFCN_y0 = linspace(1.05*min(SCC1DLEARFCN_minO,SCC1DLEARFCN_min),1.05*max(SCC1DLEARFCN_maxO,SCC1DLEARFCN_max),length(pS_SCC1DLEARFCN));
                        SCC1DLEARFCN_f0 = exp(-(SCC1DLEARFCN_y0-SCC1DLEARFCN_meanO).^2./(2*SCC1DLEARFCN_stdO^2))./(SCC1DLEARFCN_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,SCC1DLEARFCN_y0,SCC1DLEARFCN_f0,'LineWidth',3)
                    else
                        SCC1DLEARFCN_f0 = exp(-(SCC1DLEARFCN_y-SCC1DLEARFCN_meanO).^2./(2*SCC1DLEARFCN_stdO^2))./(SCC1DLEARFCN_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,SCC1DLEARFCN_y,SCC1DLEARFCN_f0,'LineWidth',3)
                    end
                    SCC1DLEARFCN_f = exp(-(SCC1DLEARFCN_y-SCC1DLEARFCN_mean).^2./(2*SCC1DLEARFCN_std^2))./(SCC1DLEARFCN_std*sqrt(2*pi));
                    plot(app.UIAxes2,SCC1DLEARFCN_y,SCC1DLEARFCN_f,'LineWidth',3)

                    title(app.UIAxes2,{'Histogram and PDF of DL EARFCN'});
                    xlabel(app.UIAxes2,'SCC1 DL EARFCN','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original SCC1 DL EARFCN Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original SCC1 DL EARFCN Data','PDF Curve for Filled SCC1 DL EARFCN Data');
                    
                case 'PCCPUSCHPower'
                    [PCCPUSCHPower_4G,~] = fillmissing(Table_4G.PCCPUSCHPower_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.PCCPUSCHPower_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,PCCPUSCHPower_4G,'Normalization','pdf')
                    pS_PCCPUSCHPower = histcounts(Table_4G.PCCPUSCHPower_4G,'Normalization','pdf');
                    p_PCCPUSCHPower= histcounts(PCCPUSCHPower_4G,'Normalization','pdf');
                    
                    PCCPUSCHPower_minO = min(Table_4G.PCCPUSCHPower_4G);
                    PCCPUSCHPower_min = min(PCCPUSCHPower_4G);
                    PCCPUSCHPower_maxO = max(Table_4G.PCCPUSCHPower_4G);
                    PCCPUSCHPower_max = max(PCCPUSCHPower_4G);
                    PCCPUSCHPower_meanO = mean(Table_4G.PCCPUSCHPower_4G,'omitnan');
                    PCCPUSCHPower_mean = mean(PCCPUSCHPower_4G);
                    PCCPUSCHPower_stdO = std(Table_4G.PCCPUSCHPower_4G,'omitnan');
                    PCCPUSCHPower_std = std(PCCPUSCHPower_4G);
                    
                    PCCPUSCHPower_y = linspace(1.05*min(PCCPUSCHPower_minO,PCCPUSCHPower_min),1.05*max(PCCPUSCHPower_maxO,PCCPUSCHPower_max),length(p_PCCPUSCHPower));
                    if length(p_PCCPUSCHPower) ~= length(pS_PCCPUSCHPower)
                        PCCPUSCHPower_y0 = linspace(1.05*min(PCCPUSCHPower_minO,PCCPUSCHPower_min),1.05*max(PCCPUSCHPower_maxO,PCCPUSCHPower_max),length(pS_PCCPUSCHPower));
                        PCCPUSCHPower_f0 = exp(-(PCCPUSCHPower_y0-PCCPUSCHPower_meanO).^2./(2*PCCPUSCHPower_stdO^2))./(PCCPUSCHPower_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,PCCPUSCHPower_y0,PCCPUSCHPower_f0,'LineWidth',3)
                    else
                        PCCPUSCHPower_f0 = exp(-(PCCPUSCHPower_y-PCCPUSCHPower_meanO).^2./(2*PCCPUSCHPower_stdO^2))./(PCCPUSCHPower_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,PCCPUSCHPower_y,PCCPUSCHPower_f0,'LineWidth',3)
                    end
                    PCCPUSCHPower_f = exp(-(PCCPUSCHPower_y-PCCPUSCHPower_mean).^2./(2*PCCPUSCHPower_std^2))./(PCCPUSCHPower_std*sqrt(2*pi));
                    plot(app.UIAxes2,PCCPUSCHPower_y,PCCPUSCHPower_f,'LineWidth',3)
                    title(app.UIAxes2,{'Histogram and PDF of PCC PUSCH Power'});
                    xlabel(app.UIAxes2,'PCC PUSCH Power (dBm)','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original PCC PUSCH Power Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original PCC PUSCH Power Data','PDF Curve for Filled PCC PUSCH Power Data');
                    
                case 'PCCPUCCHPower'
                    [PCCPUCCHPower_4G,~] = fillmissing(Table_4G.PCCPUCCHPower_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.PCCPUCCHPower_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,PCCPUCCHPower_4G,'Normalization','pdf')
                    pS_PCCPUCCHPower = histcounts(Table_4G.PCCPUCCHPower_4G,'Normalization','pdf');
                    p_PCCPUCCHPower = histcounts(PCCPUCCHPower_4G,'Normalization','pdf');
                    
                    PCCPUCCHPower_minO = min(Table_4G.PCCPUCCHPower_4G);
                    PCCPUCCHPower_min = min(PCCPUCCHPower_4G);
                    PCCPUCCHPower_maxO = max(Table_4G.PCCPUCCHPower_4G);
                    PCCPUCCHPower_max = max(PCCPUCCHPower_4G);
                    PCCPUCCHPower_meanO = mean(Table_4G.PCCPUCCHPower_4G,'omitnan');
                    PCCPUCCHPower_mean = mean(PCCPUCCHPower_4G);
                    PCCPUCCHPower_stdO = std(Table_4G.PCCPUCCHPower_4G,'omitnan');
                    PCCPUCCHPower_std = std(PCCPUCCHPower_4G);
                    
                    PCCPUCCHPower_y = linspace(1.05*min(PCCPUCCHPower_minO,PCCPUCCHPower_min),1.05*max(PCCPUCCHPower_maxO,PCCPUCCHPower_max),length(p_PCCPUCCHPower));
                    if length(p_PCCPUCCHPower) ~= length(pS_PCCPUCCHPower)
                        PCCPUCCHPower_y0 = linspace(1.05*min(PCCPUCCHPower_minO,PCCPUCCHPower_min),1.05*max(PCCPUCCHPower_maxO,PCCPUCCHPower_max),length(pS_PCCPUCCHPower));
                        PCCPUCCHPower_f0 = exp(-(PCCPUCCHPower_y0-PCCPUCCHPower_meanO).^2./(2*PCCPUCCHPower_stdO^2))./(PCCPUCCHPower_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,PCCPUCCHPower_y0,PCCPUCCHPower_f0,'LineWidth',3)
                    else
                        PCCPUCCHPower_f0 = exp(-(PCCPUCCHPower_y-PCCPUCCHPower_meanO).^2./(2*PCCPUCCHPower_stdO^2))./(PCCPUCCHPower_stdO*sqrt(2*pi));
                        plot(app.UIAxes2,PCCPUCCHPower_y,PCCPUCCHPower_f0,'LineWidth',3)
                    end
                    PCCPUCCHPower_f = exp(-(PCCPUCCHPower_y-PCCPUCCHPower_mean).^2./(2*PCCPUCCHPower_std^2))./(PCCPUCCHPower_std*sqrt(2*pi));
                    plot(app.UIAxes2,PCCPUCCHPower_y,PCCPUCCHPower_f,'LineWidth',3)

                    title(app.UIAxes2,{'Histogram and PDF of PCC PUCCH Power'});
                    xlabel(app.UIAxes2,'PCC PUCCH Power (dBm)','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original PCC PUCCH Power Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original PCC PUCCH Power Data','PDF Curve for Filled PCC PUCCH Power Data');

                    
                case 'PDCPThroughputDL'
                    [PDCPThroughputDL_4G,~] = fillmissing(Table_4G.PDCPThroughputDL_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.PDCPThroughputDL_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,PDCPThroughputDL_4G,'Normalization','pdf')
                    pS_PDCPThroughputDL = histcounts(Table_4G.PDCPThroughputDL_4G,'Normalization','pdf');
                    p_PDCPThroughputDL = histcounts(PDCPThroughputDL_4G,'Normalization','pdf');
                    
                    PDCPThroughputDL_minO = min(Table_4G.PDCPThroughputDL_4G);
                    PDCPThroughputDL_min = min(PDCPThroughputDL_4G);
                    PDCPThroughputDL_maxO = max(Table_4G.PDCPThroughputDL_4G);
                    PDCPThroughputDL_max = max(PDCPThroughputDL_4G);
                    PDCPThroughputDL_meanO = mean(Table_4G.PDCPThroughputDL_4G,'omitnan');
                    PDCPThroughputDL_mean = mean(PDCPThroughputDL_4G);
                    PDCPThroughputDL_stdO = std(Table_4G.PDCPThroughputDL_4G,'omitnan');
                    PDCPThroughputDL_std = std(PDCPThroughputDL_4G);
                    
                    PDCPThroughputDL_y = linspace(min(PDCPThroughputDL_minO,PDCPThroughputDL_min),max(PDCPThroughputDL_maxO,PDCPThroughputDL_max),length(p_PDCPThroughputDL));
                    pd1 = makedist('HalfNormal','mu',PDCPThroughputDL_meanO,'sigma',PDCPThroughputDL_stdO);
                    if length(p_PDCPThroughputDL) ~= length(pS_PDCPThroughputDL)
                        PDCPThroughputDL_y0 = linspace(min(PDCPThroughputDL_minO,PDCPThroughputDL_min),max(PDCPThroughputDL_maxO,PDCPThroughputDL_max),length(pS_PDCPThroughputDL));
                        PDCPThroughputDL_f0 = pdf(pd1,PDCPThroughputDL_y0);
                        plot(app.UIAxes2,PDCPThroughputDL_y0,PDCPThroughputDL_f0,'LineWidth',3)
                    else
                        PDCPThroughputDL_f0 = pdf(pd1,PDCPThroughputDL_y);
                        plot(app.UIAxes2,PDCPThroughputDL_y,PDCPThroughputDL_f0,'LineWidth',3)
                    end
                    pd2 = makedist('HalfNormal','mu',PDCPThroughputDL_mean,'sigma',PDCPThroughputDL_std);
                    PDCPThroughputDL_f = pdf(pd2,PDCPThroughputDL_y);
                    plot(app.UIAxes2,PDCPThroughputDL_y,PDCPThroughputDL_f,'LineWidth',3)

                    title(app.UIAxes2,{'Histogram and PDF of PDCP Throughput DL'});
                    xlabel(app.UIAxes2,'PDCP Throughput DL (bps)','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original PDCP Throughput DL Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original PDCP Throughput DL Data','PDF Curve for Filled PDCP Throughput DL Data');
                    
                case 'LTERLCThroughputDL'
                    [LTERLCThroughputDL_4G,~] = fillmissing(Table_4G.LTERLCThroughputDL_4G,'pchip',...
                        'SamplePoints',NoNum);
                    app.UIAxes2.NextPlot = 'replacechildren';
                    histogram(app.UIAxes2,Table_4G.LTERLCThroughputDL_4G,'Normalization','pdf')
                    app.UIAxes2.NextPlot = 'add';
                    histogram(app.UIAxes2,LTERLCThroughputDL_4G,'Normalization','pdf')
                    pS_LTERLCThroughputDL = histcounts(Table_4G.LTERLCThroughputDL_4G,'Normalization','pdf');
                    p_LTERLCThroughputDL = histcounts(LTERLCThroughputDL_4G,'Normalization','pdf');
                    
                    LTERLCThroughputDL_minO = min(Table_4G.LTERLCThroughputDL_4G);
                    LTERLCThroughputDL_min = min(LTERLCThroughputDL_4G);
                    LTERLCThroughputDL_maxO = max(Table_4G.LTERLCThroughputDL_4G);
                    LTERLCThroughputDL_max = max(LTERLCThroughputDL_4G);
                    LTERLCThroughputDL_meanO = mean(Table_4G.LTERLCThroughputDL_4G,'omitnan');
                    LTERLCThroughputDL_mean = mean(LTERLCThroughputDL_4G);
                    LTERLCThroughputDL_stdO = std(Table_4G.LTERLCThroughputDL_4G,'omitnan');
                    LTERLCThroughputDL_std = std(LTERLCThroughputDL_4G);
                    
                    LTERLCThroughputDL_y = linspace(1.05*min(LTERLCThroughputDL_minO,LTERLCThroughputDL_min),1.05*max(LTERLCThroughputDL_maxO,LTERLCThroughputDL_max),length(p_LTERLCThroughputDL));
                    pd3 = makedist('HalfNormal','mu',LTERLCThroughputDL_meanO,'sigma',LTERLCThroughputDL_stdO);
                    if length(p_LTERLCThroughputDL) ~= length(pS_LTERLCThroughputDL)
                        LTERLCThroughputDL_y0 = linspace(1.05*min(LTERLCThroughputDL_minO,LTERLCThroughputDL_min),1.05*max(LTERLCThroughputDL_maxO,LTERLCThroughputDL_max),length(pS_LTERLCThroughputDL));
                        LTERLCThroughputDL_f0 = pdf(pd3,LTERLCThroughputDL_y0);
                        plot(app.UIAxes2,LTERLCThroughputDL_y0,LTERLCThroughputDL_f0,'LineWidth',3)
                    else
                        LTERLCThroughputDL_f0 = pdf(pd3,LTERLCThroughputDL_y);
                        plot(app.UIAxes2,LTERLCThroughputDL_y,LTERLCThroughputDL_f0,'LineWidth',3)
                    end
                    pd4 = makedist('HalfNormal','mu',LTERLCThroughputDL_mean,'sigma',LTERLCThroughputDL_std);
                    LTERLCThroughputDL_f = pdf(pd4,LTERLCThroughputDL_y);
                    plot(app.UIAxes2,LTERLCThroughputDL_y,LTERLCThroughputDL_f,'LineWidth',3)

                    title(app.UIAxes2,{'Histogram and PDF of LTE RLC Throughput DL'});
                    xlabel(app.UIAxes2,'LTE RLC Throughput DL (bps)','FontWeight','bold');
                    ylabel(app.UIAxes2,'Probability Density','FontWeight','bold');
                    legend(app.UIAxes2,'Normalized Histogram of the Original LTE RLC Throughput DL Data','Normalize Histogram with Missing Data Filled',...
                        'PDF Curve for Original LTE RLC Throughput DL Data','PDF Curve for Filled LTE RLC Throughput DL Data');
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.PropertyDropDownLabel = uilabel(app.UIFigure);
            app.PropertyDropDownLabel.Text = 'Property';
            app.PropertyDropDown = uidropdown(app.UIFigure);
            app.PropertyDropDown.Items = {'RSRP', 'RSRQ', 'RSSI', 'PCCSINR', 'PCI', 'DLEARFCN', 'SCC1RSRP', 'SCC1RSRQ', 'SCC1RSSI', 'SCC1PCI', 'SCC1SINR', 'SCC1DLEARFCN', 'PCCPUSCHPower', 'PCCPUCCHPower', 'PDCPThroughputDL', 'LTERLCThroughputDL'};
            app.PropertyDropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyDropDownValueChanged, true);
            app.PropertyDropDown.Value = 'RSRP';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'No'; 'Longitude'; 'Latitude'; 'RSRP'; 'RSRQ'; 'RSSI'; 'PCCSINR'; 'PCI'; 'DLEARFCN'; 'SCC1RSRP'; 'SCC1RSRQ'; 'SCC1RSSI'; 'SCC1PCI'; 'SCC1SINR'; 'SCC1DLEARFCN'; 'PCCPUSCHPower'; 'PCCPUCCHPower'; 'PDCPThroughDL'; 'LTERLCThroughputDL'; 'NoNum'};
            app.UITable.RowName = {};
            app.HistPDFCDFDropDownLabel = uilabel(app.UIFigure);
            app.HistPDFCDFDropDownLabel.Text = 'Hist, PDF & CDF';
            app.HistPDFCDFDropDown = uidropdown(app.UIFigure);
            app.HistPDFCDFDropDown.Items = {'RSRP', 'RSRQ', 'RSSI', 'PCCSINR', 'PCI', 'DLEARFCN', 'SCC1RSRP', 'SCC1RSRQ', 'SCC1RSSI', 'SCC1PCI', 'SCC1SINR', 'SCC1DLEARFCN', 'PCCPUSCHPower', 'PCCPUCCHPower', 'PDCPThroughputDL', 'LTERLCThroughputDL'};
            app.HistPDFCDFDropDown.ValueChangedFcn = createCallbackFcn(app, @HistPDFCDFDropDownValueChanged, true);
            app.HistPDFCDFDropDown.Value = 'RSRP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LTEPredictParams

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end